classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimplexeUIFigure          matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        ButtonGroup               matlab.ui.container.ButtonGroup
        MinimisationButton        matlab.ui.control.RadioButton
        MaximisationButton        matlab.ui.control.RadioButton
        Panel_2                   matlab.ui.container.Panel
        EditField_2               matlab.ui.control.EditField
        EditField_3               matlab.ui.control.EditField
        EntrerbLabel              matlab.ui.control.Label
        EntrercLabel              matlab.ui.control.Label
        Panel_3                   matlab.ui.container.Panel
        ResoudreButton            matlab.ui.control.Button
        EffacerButton             matlab.ui.control.Button
        AfficherButton            matlab.ui.control.Button
        Panel_5                   matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        VecteurSolutionLabel      matlab.ui.control.Label
        UITable_2                 matlab.ui.control.Table
        TableDeDonnesLabel        matlab.ui.control.Label
        ValeurdelafonctionObjectiveLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        OULabel                   matlab.ui.control.Label
        ImportertableauxlsButton  matlab.ui.control.Button
        Panel_6                   matlab.ui.container.Panel
        EntrerlamatriceALabel     matlab.ui.control.Label
        EditField_4               matlab.ui.control.EditField
        EditField_5               matlab.ui.control.EditField
        EditField_6               matlab.ui.control.EditField
        Panel_4                   matlab.ui.container.Panel
        MiniProjetdeProgrammationLineaireLabel  matlab.ui.control.Label
        YassineMoulatElAliLabel   matlab.ui.control.Label
    end


    properties (Access = private)
        IP % Description
        OP
        A
        b
        c
        Fobj
        TFinal
        VFinal
        ColNom
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MinimisationButton.Value=false;
            app.MaximisationButton.Value=false;
        end

        % Button pushed function: ImportertableauxlsButton
        function ImportertableauxlsButtonPushed(app, event)
            [nomFichier,Traget] = uigetfile({'*.xls'},'Selecteur de Fichier');
            app.IP = xlsread(strcat(Traget,nomFichier));
            app.SimplexeUIFigure.Visible = 'off';
            app.SimplexeUIFigure.Visible = 'on';
            %app.UITable_2.Data = [app.IP];
            app.A = app.IP;
            if(app.EditField_2.Value=="" && app.EditField_2.Value=="")
                app.UITable_2.ColumnName = "Veuillez remplir premierement les champs b et c";
            else
                app.b = app.EditField_2.Value;
                app.c = app.EditField_3.Value;
                if(app.MinimisationButton.Value == true)
                    [app.UITable_2.Data,~,app.UITable_2.ColumnName] = tabInitialGeneral(app.A,transpose(str2num(app.b)),str2num(app.c),"min");
                elseif(app.MaximisationButton.Value == true)
                    [app.UITable_2.Data,~,app.UITable_2.ColumnName] = tabInitialGeneral(app.A,transpose(str2num(app.b)),str2num(app.c),"max");
                end
            end
            
        end

        % Button pushed function: EffacerButton
        function EffacerButtonPushed(app, event)
            app.EditField_2.Value = "";
            app.EditField_3.Value = "";
            app.EditField_4.Value = "";
            app.EditField_5.Value = "";
            app.EditField_6.Value = "";
            app.MinimisationButton.Value = false;
            app.MaximisationButton.Value = false;
            app.UITable.Data = [];
            app.UITable_2.Data = [];
            app.Label.Text ="";
        end

        % Button pushed function: ResoudreButton
        function ResoudreButtonPushed(app, event)
           if(app.EditField_2.Value=="" && app.EditField_2.Value=="")
                app.UITable_2.ColumnName = "Veuillez remplir premierement les champs b et c";
            else
                if(app.MinimisationButton.Value == true)
                    [app.TFinal,app.VFinal,app.Fobj,app.ColNom] = Simplexe(app.A,transpose(str2num(app.b)),str2num(app.c),"min");
                elseif(app.MaximisationButton.Value == true)
                    [app.TFinal,app.VFinal,app.Fobj,app.ColNom] = Simplexe(app.A,transpose(str2num(app.b)),str2num(app.c),"max");
                end
           end
            app.TableDeDonnesLabel.Text = "Table Finale de Simplexe";
            app.UITable_2.Data = app.TFinal;
            app.UITable_2.ColumnName = app.ColNom;
            app.UITable.Data = app.VFinal;
            app.UITable.ColumnName = app.ColNom;
            app.Label.Text = num2str(app.Fobj);
        end

        % Callback function
        function EditFieldValueChanged(app, event)
        
        end

        % Button pushed function: AfficherButton
        function AfficherButtonPushed(app, event)
            if(app.EditField_4.Value=="" || app.EditField_5.Value=="" || app.EditField_6.Value=="" || app.EditField_2.Value=="" || app.EditField_3.Value=="")
                app.UITable_2.ColumnName = "Veuillez remplir tous les champs pour afficher tableau";
            else
                app.A = [str2num(app.EditField_4.Value) ; str2num(app.EditField_5.Value) ; str2num(app.EditField_6.Value)];
                app.b = app.EditField_2.Value;
                app.c = app.EditField_3.Value;
                if(app.MinimisationButton.Value == true)
                    [app.UITable_2.Data,~,app.UITable_2.ColumnName] = tabInitialGeneral(app.A,transpose(str2num(app.b)),str2num(app.c),"min");
                elseif(app.MaximisationButton.Value == true)
                    [app.UITable_2.Data,~,app.UITable_2.ColumnName] = tabInitialGeneral(app.A,transpose(str2num(app.b)),str2num(app.c),"max");
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimplexeUIFigure = uifigure;
            app.SimplexeUIFigure.Name = 'Simplexe';
            app.Panel = uipanel(app.SimplexeUIFigure);
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.MinimisationButton = uiradiobutton(app.ButtonGroup);
            app.MinimisationButton.Text = 'Minimisation';
            app.MinimisationButton.Value = true;
            app.MaximisationButton = uiradiobutton(app.ButtonGroup);
            app.MaximisationButton.Text = 'Maximisation';
            app.Panel_2 = uipanel(app.Panel);
            app.EditField_2 = uieditfield(app.Panel_2, 'text');
            app.EditField_3 = uieditfield(app.Panel_2, 'text');
            app.EntrerbLabel = uilabel(app.Panel_2);
            app.EntrerbLabel.Text = 'Entrer b';
            app.EntrercLabel = uilabel(app.Panel_2);
            app.EntrercLabel.Text = 'Entrer c';
            app.Panel_3 = uipanel(app.Panel);
            app.ResoudreButton = uibutton(app.Panel_3, 'push');
            app.ResoudreButton.ButtonPushedFcn = createCallbackFcn(app, @ResoudreButtonPushed, true);
            app.ResoudreButton.Text = 'Resoudre';
            app.EffacerButton = uibutton(app.Panel_3, 'push');
            app.EffacerButton.ButtonPushedFcn = createCallbackFcn(app, @EffacerButtonPushed, true);
            app.EffacerButton.Text = 'Effacer';
            app.AfficherButton = uibutton(app.Panel_3, 'push');
            app.AfficherButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherButtonPushed, true);
            app.AfficherButton.Text = 'Afficher';
            app.Panel_5 = uipanel(app.Panel);
            app.UITable = uitable(app.Panel_5);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.VecteurSolutionLabel = uilabel(app.Panel_5);
            app.VecteurSolutionLabel.Text = 'Vecteur Solution';
            app.UITable_2 = uitable(app.Panel_5);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.TableDeDonnesLabel = uilabel(app.Panel_5);
            app.TableDeDonnesLabel.Text = 'Table De Données';
            app.ValeurdelafonctionObjectiveLabel = uilabel(app.Panel_5);
            app.ValeurdelafonctionObjectiveLabel.Text = 'Valeur de la fonction Objective = ';
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = '';
            app.OULabel = uilabel(app.Panel);
            app.OULabel.Text = 'OU';
            app.ImportertableauxlsButton = uibutton(app.Panel, 'push');
            app.ImportertableauxlsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportertableauxlsButtonPushed, true);
            app.ImportertableauxlsButton.Text = 'Importer tableau(.xls)';
            app.Panel_6 = uipanel(app.Panel);
            app.EntrerlamatriceALabel = uilabel(app.Panel_6);
            app.EntrerlamatriceALabel.Text = 'Entrer la matrice A';
            app.EditField_4 = uieditfield(app.Panel_6, 'text');
            app.EditField_5 = uieditfield(app.Panel_6, 'text');
            app.EditField_6 = uieditfield(app.Panel_6, 'text');
            app.Panel_4 = uipanel(app.SimplexeUIFigure);
            app.MiniProjetdeProgrammationLineaireLabel = uilabel(app.Panel_4);
            app.MiniProjetdeProgrammationLineaireLabel.Text = 'Mini Projet de Programmation Lineaire';
            app.YassineMoulatElAliLabel = uilabel(app.SimplexeUIFigure);
            app.YassineMoulatElAliLabel.Text = '@Yassine Moulat El Ali';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimplexeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimplexeUIFigure)
        end
    end
end